classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ZeroOrderZoomingButton       matlab.ui.control.Button
        TransposeNegativeButton      matlab.ui.control.Button
        EditField_6                  matlab.ui.control.EditField
        EditField_5                  matlab.ui.control.EditField
        EditField_4                  matlab.ui.control.EditField
        RotateButton                 matlab.ui.control.Button
        UpDownCheckBox               matlab.ui.control.CheckBox
        LeftRightCheckBox            matlab.ui.control.CheckBox
        FlipButton                   matlab.ui.control.Button
        ContrastStretchingButton     matlab.ui.control.Button
        IntensitylevelSlicingButton  matlab.ui.control.Button
        ColorCheckBox                matlab.ui.control.CheckBox
        MedianFilterButton           matlab.ui.control.Button
        SaltpepperButton             matlab.ui.control.Button
        AverageFilterButton          matlab.ui.control.Button
        EditField_3                  matlab.ui.control.EditField
        EditField_2                  matlab.ui.control.EditField
        EditField                    matlab.ui.control.EditField
        FullPathLabel                matlab.ui.control.Label
        PathNameLabel                matlab.ui.control.Label
        FileNameLabel                matlab.ui.control.Label
        ProcessedImageLabel          matlab.ui.control.Label
        OriginalImageLabel           matlab.ui.control.Label
        BrightnessButtonSlider       matlab.ui.control.Slider
        BrightnessButtonSliderLabel  matlab.ui.control.Label
        KTimeZoomingGrayButton       matlab.ui.control.Button
        KTimeZoomingRGBButton        matlab.ui.control.Button
        RGBToGrayButton              matlab.ui.control.Button
        SaveButton                   matlab.ui.control.Button
        BrowseFileButton             matlab.ui.control.Button
        UIAxes_3                     matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
           Image  % Description
           PathFill        % the public path to edite file 
           FileName        % file name
           ProcessedImage  %image after proccess
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseFileButton
        function BrowseFileButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp','Images'}, 'Select Image');
        
            if isequal(file, 0)
                return;
            end
    
            img = imread(fullfile(path, file));
            img = im2double(img);
            app.Image=img;
            app.PathFill=fullfile(path,file);
            app.FileName=file;
            imshow(img, 'Parent', app.UIAxes_3);
            axis(app.UIAxes_3, 'image');
            axis(app.UIAxes_3, 'off');
            app.EditField_2.Value=path;
            app.EditField.Value=file;
            app.EditField_3.Value=fullfile(path, file);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
                
        % Ask user where to save the file
         [file, path] = uiputfile({...
        '*.jpg','JPEG Image (*.jpg)'; 
        '*.png','PNG Image (*.png)'; 
        '*.tif','TIFF Image (*.tif)'; 
        '*.bmp','Bitmap Image (*.bmp)'}, ...
        'Save Image As');

        % User pressed Cancel
        if isequal(file,0)
            return;
        end
    
        % Full file path
        fullpath = fullfile(path, file);
        % Example image to save (replace with your image variable)
        imgToSave = app.ProcessedImage ;   % or app.OriginalImage
        % Save the image
        imwrite(imgToSave, fullpath);
        msgbox('Image saved successfully','Success');

        end

        % Button pushed function: RGBToGrayButton
        function RGBToGrayButtonPushed(app, event)
            % im=rgb2gray(app.Image);
            % imshow(im);

               img=double(app.Image);
               [m, n, ~] = size(img);     
               gray = zeros(m, n);        
               for row = 1:m
                for col = 1:n
                    R = img(row, col, 1);
                    G = img(row, col, 2);
                    B = img(row, col, 3);
                    gray(row, col) = 0.2989*R + 0.5870*G + 0.1140*B;
                end
               end
                    app.ProcessedImage=gray;
                    imshow(gray, 'Parent', app.UIAxes_2);
                    axis(app.UIAxes_2, 'image');
                    axis(app.UIAxes_2, 'off');
        end

        % Button pushed function: KTimeZoomingRGBButton
        function KTimeZoomingRGBButtonPushed(app, event)
        %    K=20;
        %  if isfloat(app.Image)
        %     img = im2uint8(app.Image);   % convert to uint8
        % else
        %     img = app.Image;
        % end
        %     [m, n, c] = size(app.Image);
        %     zoomed = zeros(m*K, n*K, c,'uint8');
        % 
        % for i = 1:m
        %     for j = 1:n
        %         for x= 1:c
        %             zoomed((i-1)*K+1:i*K, (j-1)*K+1:j*K, x) =img(i,j,x);
        %         end
        %     end
        % end
        %     app.ProcessedImage=zoomed;
        %     imshow(zoomed, 'Parent', app.UIAxes_2);
        %     axis(app.UIAxes_2, 'image');
        %     axis(app.UIAxes_2, 'off');
        img=app.Image;
        [n,m,t]=size(img);
        k=str2double(app.EditField_5.Value);
        f=zeros(k*n,k*m,t);
        c = 1; 
        r = 1; 
       for o=1:t
        for x = 1:size(img, 1) 
           for y = 1:size(img, 2) 
               f(r:r+k-1, c:c+k-1,o) = img(x, y,o); 
               c = c + k; 
           end 
           c = 1; 
           r = r + k; 
        end 
        r=1;
       end
        app.ProcessedImage = f;
        imshow(f, 'Parent', app.UIAxes_2); 
        axis(app.UIAxes_2, 'image');        
        axis(app.UIAxes_2, 'off');       
        zoom(app.UIAxes_2, 'on');             
        pan(app.UIAxes_2, 'on'); 
        end

        % Button pushed function: KTimeZoomingGrayButton
        function KTimeZoomingGrayButtonPushed(app, event)
        %      K=20;
        %  if isfloat(app.Image)
        %     img = im2uint8(app.Image);   % convert to uint8
        % else
        %     img = app.Image;
        % end
        %     [m, n, c] = size(app.Image);
        %     zoomed = zeros(m*K, n*K,'uint8');
        % 
        % for i = 1:m
        %     for j = 1:n
        % 
        %             zoomed((i-1)*K+1:i*K, (j-1)*K+1:j*K) =img(i,j);
        % 
        %     end
        % end
        %     app.ProcessedImage=zoomed;
        %     imshow(zoomed, 'Parent', app.UIAxes_2);
        %     axis(app.UIAxes_2, 'image');
        %     axis(app.UIAxes_2, 'off');
        img=app.Image;
        [n,m]=size(img);
        k=str2double(app.EditField_6.Value);
        f=zeros(k*n,k*m);
        c = 1; 
        r = 1; 
        for x = 1:size(img, 1) 
           for y = 1:size(img, 2) 
               f(r:r+k-1, c:c+k-1) = img(x, y); 
               c = c + k; 
           end 
           c = 1; 
           r = r + k; 
        end 
        app.ProcessedImage = f;
        imshow(f, 'Parent', app.UIAxes_2); 
        axis(app.UIAxes_2, 'image');        
        axis(app.UIAxes_2, 'off');       
        zoom(app.UIAxes_2, 'on');             
        end

        % Value changed function: BrightnessButtonSlider
        function BrightnessButtonSliderValueChanged(app, event)
            c = app.BrightnessButtonSlider.Value;
            img =double(app.Image);                       
            Transform = c * log(1 + img);
            Transform = uint8(Transform);
            app.ProcessedImage = Transform;
            imshow(Transform, 'Parent', app.UIAxes_2);
            axis(app.UIAxes_2, 'image');
            axis(app.UIAxes_2, 'off');
        end

        % Button pushed function: AverageFilterButton
        function AverageFilterButtonPushed(app, event)
           if(app.ColorCheckBox.Value)
            img = app.Image;
            [n,m,~] = size(img);
            k = (1/9);
            f = zeros(n,m,3);
            for c = 1:3      
                for i = 1:n-2
                    for j =1:m-2
                        f(i,j,c) =k*sum(sum(img(i:i+2, j:j+2, c)));
                    end
                end
            end
            imshow(f, 'Parent', app.UIAxes_2);
            app.ProcessedImage = f;
           else

            img=app.Image;
            [n,m]=size(img);
            f=zeros(n,m);
            k=(1/9);
            for i=1:n-2
                for j=1:m-2
                    f(i,j)=k*sum(sum(img(i:i+2,j:j+2)));
                end
            end
            imshow(f, 'Parent', app.UIAxes_2);
            app.ProcessedImage=f;
            axis(app.UIAxes_2, 'image');
            axis(app.UIAxes_2, 'off');
          end                     
        end

        % Button pushed function: SaltpepperButton
        function SaltpepperButtonPushed(app, event)
             img=app.Image;
             img=imnoise(img,"salt & pepper",0.05);
             imshow(img, 'Parent', app.UIAxes_2);
             app.ProcessedImage=img;
             axis(app.UIAxes_2, 'image');
             axis(app.UIAxes_2, 'off');
        end

        % Button pushed function: MedianFilterButton
        function MedianFilterButtonPushed(app, event)
         img=app.Image;
         [n,m]=size(img);
         f=zeros(n,m);
         if ndims(img) == 3
            img = rgb2gray(img);
         end
         for x = 1 : size(img, 1) - 2 
           for y = 1:size(img, 2) - 2 
               o = sort(img(x:x+2, y:y+2)); 
               f(x,y) = o(5); 
           end 
         end 
             imshow(f, 'Parent', app.UIAxes_2);
             app.ProcessedImage=f;
             axis(app.UIAxes_2, 'image');
             axis(app.UIAxes_2, 'off');

        % % Min Filter (Used for removing Salt noise) 
        % for x = 1 : size(img, 1) - 2 
        %    for y = 1 : size(img, 2) - 2 
        %        o = min(min(img(x:x+2, y:y+2))); 
        %        f(x,y) = o; 
        %    end 
        % end 
        % 
        % 
        % 
        % % Max Filter (Used for removing Pepper noise) 
        % for x = 1 : size(img, 1) - 2 
        %    for y = 1 : size(img, 2) - 2 
        %        o = max(max(img(x:x+2, y:y+2))); 
        %        f(x,y) = o; 
        %    end 
        % end 


        end

        % Button pushed function: IntensitylevelSlicingButton
        function IntensitylevelSlicingButtonPushed(app, event)
        img =im2uint8(app.Image);
        s =img;
        for x =1:size(img, 1)
            for y = 1:size(img, 2)
                if img(x,y) <50 && img(x,y) >10
                    s(x,y) = 255;
                else
                    s(x,y) = img(x,y);
                end
            end
        end
        
        imshow(s, 'Parent', app.UIAxes_2);
        axis(app.UIAxes_2,'image');
        axis(app.UIAxes_2,'off');
        app.ProcessedImage = s;

        end

        % Button pushed function: ContrastStretchingButton
        function ContrastStretchingButtonPushed(app, event)
        img = im2uint8(app.Image); 
        x_min = min(img(:)); 
        x_max = max(img(:)); 
        delta = x_max - x_min; 
        new = ((img - x_min) / delta) * 255;
          app.ProcessedImage = new;
          imshow(new, 'Parent', app.UIAxes_2);
          axis(app.UIAxes_2, 'image');
          axis(app.UIAxes_2, 'off');
        end

        % Button pushed function: FlipButton
        function FlipButtonPushed(app, event)
            imm=app.Image;
            if (app.LeftRightCheckBox.Value)
                imm=fliplr(imm);
            end
            if (app.UpDownCheckBox.Value)
                imm = flipud(imm);
            end
          app.ProcessedImage = imm;
          imshow(imm, 'Parent', app.UIAxes_2);
          axis(app.UIAxes_2, 'image');
          axis(app.UIAxes_2, 'off');
        end

        % Button pushed function: RotateButton
        function RotateButtonPushed(app, event)
            value=app.EditField_4.Value;
            img=app.Image;
            imm=rot90(img,str2double(value));
            app.ProcessedImage = imm;
            imshow(imm, 'Parent', app.UIAxes_2);
            axis(app.UIAxes_2, 'image');
            axis(app.UIAxes_2, 'off');
        end

        % Button pushed function: TransposeNegativeButton
        function TransposeNegativeButtonPushed(app, event)
        img = app.Image;
        % Transpose
        img_t = img.'; 
        % Negative
        img_t_neg = 1 - img_t;
        app.ProcessedImage = img_t_neg;
        imshow(app.ProcessedImage, 'Parent', app.UIAxes_2);
        axis(app.UIAxes_2, 'image');
        axis(app.UIAxes_2, 'off');
        end

        % Button pushed function: ZeroOrderZoomingButton
        function ZeroOrderZoomingButtonPushed(app, event)
        img = app.Image;  
        [rows, cols] = size(img); 
        intermediate_rows = 2*rows - 1;
        row_interpolated_img = zeros(intermediate_rows, cols); 
        
        for i = 1:rows
            row_interpolated_img(2*i-1,:) = img(i,:);
            if i < rows
                row_interpolated_img(2*i,:) = (img(i,:) + img(i+1,:)) / 2;
            end
        end
        
        intermediate_cols = 2*cols - 1;
        zoomed_img = zeros(intermediate_rows, intermediate_cols); 
        
        for j = 1:cols
            zoomed_img(:,2*j-1) = row_interpolated_img(:,j);
            if j < cols
                zoomed_img(:,2*j) = (row_interpolated_img(:,j) + row_interpolated_img(:,j+1)) / 2;
            end
        end
        
        app.ProcessedImage = zoomed_img;
        imshow(app.ProcessedImage, 'Parent', app.UIAxes_2);
        axis(app.UIAxes_2,'image');
        axis(app.UIAxes_2,'off');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.BrowseFileButton = uibutton(app.UIFigure, 'push');
            app.BrowseFileButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseFileButtonPushed, true);
            app.BrowseFileButton.Tag = 'Select_Imge';
            app.BrowseFileButton.Text = 'Browse File';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Tag = 'Select_Imge';
            app.SaveButton.Text = 'Save ';
            app.RGBToGrayButton = uibutton(app.UIFigure, 'push');
            app.RGBToGrayButton.ButtonPushedFcn = createCallbackFcn(app, @RGBToGrayButtonPushed, true);
            app.RGBToGrayButton.Tag = 'Select_Imge';
            app.RGBToGrayButton.Text = 'RGB To Gray';
            app.KTimeZoomingRGBButton = uibutton(app.UIFigure, 'push');
            app.KTimeZoomingRGBButton.ButtonPushedFcn = createCallbackFcn(app, @KTimeZoomingRGBButtonPushed, true);
            app.KTimeZoomingRGBButton.Tag = 'Select_Imge';
            app.KTimeZoomingRGBButton.Text = 'K-Time Zooming RGB';
            app.KTimeZoomingGrayButton = uibutton(app.UIFigure, 'push');
            app.KTimeZoomingGrayButton.ButtonPushedFcn = createCallbackFcn(app, @KTimeZoomingGrayButtonPushed, true);
            app.KTimeZoomingGrayButton.Tag = 'Select_Imge';
            app.KTimeZoomingGrayButton.Text = 'K-Time Zooming Gray';
            app.BrightnessButtonSliderLabel = uilabel(app.UIFigure);
            app.BrightnessButtonSliderLabel.Text = 'BrightnessButton';
            app.BrightnessButtonSlider = uislider(app.UIFigure);
            app.BrightnessButtonSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180 190 200];
            app.BrightnessButtonSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessButtonSliderValueChanged, true);
            app.OriginalImageLabel = uilabel(app.UIFigure);
            app.OriginalImageLabel.Text = 'Original Image';
            app.ProcessedImageLabel = uilabel(app.UIFigure);
            app.ProcessedImageLabel.Text = 'Processed Image';
            app.FileNameLabel = uilabel(app.UIFigure);
            app.FileNameLabel.Text = 'File Name';
            app.PathNameLabel = uilabel(app.UIFigure);
            app.PathNameLabel.Text = 'Path Name';
            app.FullPathLabel = uilabel(app.UIFigure);
            app.FullPathLabel.Text = 'Full Path';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.AverageFilterButton = uibutton(app.UIFigure, 'push');
            app.AverageFilterButton.ButtonPushedFcn = createCallbackFcn(app, @AverageFilterButtonPushed, true);
            app.AverageFilterButton.Tag = 'Select_Imge';
            app.AverageFilterButton.Text = 'Average Filter ';
            app.SaltpepperButton = uibutton(app.UIFigure, 'push');
            app.SaltpepperButton.ButtonPushedFcn = createCallbackFcn(app, @SaltpepperButtonPushed, true);
            app.SaltpepperButton.Tag = 'Select_Imge';
            app.SaltpepperButton.Text = 'Salt & pepper';
            app.MedianFilterButton = uibutton(app.UIFigure, 'push');
            app.MedianFilterButton.ButtonPushedFcn = createCallbackFcn(app, @MedianFilterButtonPushed, true);
            app.MedianFilterButton.Tag = 'Select_Imge';
            app.MedianFilterButton.Text = 'Median Filter ';
            app.ColorCheckBox = uicheckbox(app.UIFigure);
            app.ColorCheckBox.Text = 'Color';
            app.IntensitylevelSlicingButton = uibutton(app.UIFigure, 'push');
            app.IntensitylevelSlicingButton.ButtonPushedFcn = createCallbackFcn(app, @IntensitylevelSlicingButtonPushed, true);
            app.IntensitylevelSlicingButton.Tag = 'Select_Imge';
            app.IntensitylevelSlicingButton.Text = 'Intensity-level Slicing';
            app.ContrastStretchingButton = uibutton(app.UIFigure, 'push');
            app.ContrastStretchingButton.ButtonPushedFcn = createCallbackFcn(app, @ContrastStretchingButtonPushed, true);
            app.ContrastStretchingButton.Tag = 'Select_Imge';
            app.ContrastStretchingButton.Text = 'Contrast Stretching';
            app.FlipButton = uibutton(app.UIFigure, 'push');
            app.FlipButton.ButtonPushedFcn = createCallbackFcn(app, @FlipButtonPushed, true);
            app.FlipButton.Tag = 'Select_Imge';
            app.FlipButton.Text = 'Flip';
            app.LeftRightCheckBox = uicheckbox(app.UIFigure);
            app.LeftRightCheckBox.Text = 'Left-Right';
            app.UpDownCheckBox = uicheckbox(app.UIFigure);
            app.UpDownCheckBox.Text = 'Up-Down';
            app.RotateButton = uibutton(app.UIFigure, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed, true);
            app.RotateButton.Tag = 'Select_Imge';
            app.RotateButton.Text = 'Rotate';
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.EditField_5 = uieditfield(app.UIFigure, 'text');
            app.EditField_6 = uieditfield(app.UIFigure, 'text');
            app.TransposeNegativeButton = uibutton(app.UIFigure, 'push');
            app.TransposeNegativeButton.ButtonPushedFcn = createCallbackFcn(app, @TransposeNegativeButtonPushed, true);
            app.TransposeNegativeButton.Tag = 'Select_Imge';
            app.TransposeNegativeButton.Text = 'Transpose & Negative';
            app.ZeroOrderZoomingButton = uibutton(app.UIFigure, 'push');
            app.ZeroOrderZoomingButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroOrderZoomingButtonPushed, true);
            app.ZeroOrderZoomingButton.Tag = 'Select_Imge';
            app.ZeroOrderZoomingButton.Text = 'Zero Order Zooming';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end